classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalGeneratorUIFigure     matlab.ui.Figure
        BreakpointsEditField        matlab.ui.control.NumericEditField
        EditButton                  matlab.ui.control.Button
        BreakpointsEditFieldLabel   matlab.ui.control.Label
        GenerateButton              matlab.ui.control.Button
        EndtimeEditField            matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel       matlab.ui.control.Label
        StarttimeEditField          matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel     matlab.ui.control.Label
        SamplingrateEditField       matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel  matlab.ui.control.Label
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        samplingFrequency % sampling frequency
        startTime % start time
        endTime % end time
        breakpointsNumber % number of breakpoints
        bPoints % breakpoints
        signal % signal
        time % time
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.samplingFrequency = app.SamplingrateEditField.Value;
            app.startTime = app.StarttimeEditField.Value;
            app.endTime = app.EndtimeEditField.Value;
            app.breakpointsNumber = app.BreakpointsEditField.Value;
            if (app.breakpointsNumber > 0)
                app.bPoints = breakpoints(app.startTime, app.endTime, app.breakpointsNumber);
            else
                app.bPoints = 0;
            end
            app.signal = signalGenerator(app.samplingFrequency, app.startTime, app.endTime, app.bPoints, app.breakpointsNumber);
            app.time = linspace(app.startTime, app.endTime,(app.endTime - app.startTime)*app.samplingFrequency );
            %figure(1);
            app.UIAxes.Visible = 'on';
            plot(app.UIAxes, app.signal);
        end

        % Value changed function: BreakpointsEditField
        function BreakpointsEditFieldValueChanged(app, event)
%             app.breakpointsNumber = app.BreakpointsEditField.Value;
%             if (app.breakpointsNumber > 0)
%                 app.bPoints = breakpoints(app.startTime, app.endTime, app.breakpointsNumber);
%             else
%                 app.bPoints = 0;
%             end
        end

        % Value changed function: SamplingrateEditField
        function SamplingrateEditFieldValueChanged(app, event)
            app.samplingFrequency = app.SamplingrateEditField.Value;
        end

        % Value changed function: StarttimeEditField
        function StarttimeEditFieldValueChanged(app, event)
            app.startTime = app.StarttimeEditField.Value;
        end

        % Value changed function: EndtimeEditField
        function EndtimeEditFieldValueChanged(app, event)
            app.endTime = app.EndtimeEditField.Value;
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            [modifiedSignal,operationTime] = operations(app.signal,app.time);
            cla(app.UIAxes);
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'on';
            app.UIAxes2_2.Visible = 'on';
            plot(app.UIAxes2, app.time, app.signal);
            plot(app.UIAxes2_2, operationTime, modifiedSignal);
            app.UIAxes2_2.YLim = ([min([min(app.signal) min(modifiedSignal)]) max([max(app.signal) max(modifiedSignal)])]);
            app.signal = modifiedSignal;
            app.time = operationTime;
        end

        % Size changed function: SignalGeneratorUIFigure
        function SignalGeneratorUIFigureSizeChanged(app, event)
            app.EditButton.Layout = app.GenerateButton.Layout;        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalGeneratorUIFigure = uifigure('Visible', 'off');
            app.SignalGeneratorUIFigure.Name = 'Signal Generator';
            app.SignalGeneratorUIFigure.SizeChangedFcn = createCallbackFcn(app, @SignalGeneratorUIFigureSizeChanged, true);
            app.UIAxes = uiaxes(app.SignalGeneratorUIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.SignalGeneratorUIFigure);
            title(app.UIAxes2, 'Original Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes2_2 = uiaxes(app.SignalGeneratorUIFigure);
            title(app.UIAxes2_2, 'Modified Signal')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.Visible = 'off';
            app.SamplingrateEditFieldLabel = uilabel(app.SignalGeneratorUIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.SignalGeneratorUIFigure, 'numeric');
            app.SamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanged, true);
            app.StarttimeEditFieldLabel = uilabel(app.SignalGeneratorUIFigure);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.SignalGeneratorUIFigure, 'numeric');
            app.StarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeEditFieldValueChanged, true);
            app.EndtimeEditFieldLabel = uilabel(app.SignalGeneratorUIFigure);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.SignalGeneratorUIFigure, 'numeric');
            app.EndtimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndtimeEditFieldValueChanged, true);
            app.GenerateButton = uibutton(app.SignalGeneratorUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.BreakpointsEditFieldLabel = uilabel(app.SignalGeneratorUIFigure);
            app.BreakpointsEditFieldLabel.Text = 'Breakpoints';
            app.EditButton = uibutton(app.SignalGeneratorUIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.BreakpointsEditField = uieditfield(app.SignalGeneratorUIFigure, 'numeric');
            app.BreakpointsEditField.ValueChangedFcn = createCallbackFcn(app, @BreakpointsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.SignalGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalGeneratorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalGeneratorUIFigure)
        end
    end
end